classdef cw_1946553 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        PlanksTab                 matlab.ui.container.Tab
        RunButton                 matlab.ui.control.Button
        EstimateDropDownLabel     matlab.ui.control.Label
        EstimateDropDown          matlab.ui.control.DropDown
        SquaresEditFieldLabel     matlab.ui.control.Label
        SquaresEditField          matlab.ui.control.NumericEditField
        SquareSizeEditFieldLabel  matlab.ui.control.Label
        SquareSizeEditField       matlab.ui.control.NumericEditField
        PlanksEditFieldLabel      matlab.ui.control.Label
        PlanksEditField           matlab.ui.control.NumericEditField
        PlankSizeEditFieldLabel   matlab.ui.control.Label
        PlankSizeEditField        matlab.ui.control.NumericEditField
        MaxPlotEditFieldLabel     matlab.ui.control.Label
        MaxPlotEditField          matlab.ui.control.NumericEditField
        TilesTab                  matlab.ui.container.Tab
        NeedlesEditFieldLabel     matlab.ui.control.Label
        NeedlesEditField          matlab.ui.control.NumericEditField
        NeedleSizeEditFieldLabel  matlab.ui.control.Label
        NeedleSizeEditField       matlab.ui.control.NumericEditField
        TileswhEditFieldLabel     matlab.ui.control.Label
        TileswhEditField          matlab.ui.control.NumericEditField
        TileSizeEditFieldLabel    matlab.ui.control.Label
        TileSizeEditField         matlab.ui.control.NumericEditField
        RunButton_2               matlab.ui.control.Button
        MaxPlotEditField_2Label   matlab.ui.control.Label
        MaxPlotEditField_2        matlab.ui.control.NumericEditField
        FontTab                   matlab.ui.container.Tab
        FontColourDropDownLabel   matlab.ui.control.Label
        FontColourDropDown        matlab.ui.control.DropDown
        FontSizeEditFieldLabel    matlab.ui.control.Label
        FontSizeEditField         matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function piEstimate = estimate_pi(app)
            squares = app.SquaresEditField.Value;
            side_length = app.SquareSizeEditField.Value;
            plank_length = app.PlankSizeEditField.Value;
            planks = app.PlanksEditField.Value;
            angles = rand(1,squares)*360;
            
            a = rand(2,squares)*planks*plank_length;
            b = a+[side_length*cosd(angles+90);side_length*sind(angles+90)];
            c = b+[side_length*cosd(angles+180);side_length*sind(angles+180)];
            d = c+[side_length*cosd(angles+270);side_length*sind(angles+270)];
            
            x = [a(1,:);b(1,:);c(1,:);d(1,:);a(1,:)];
            y = [a(2,:);b(2,:);c(2,:);d(2,:);a(2,:)];
            
            diagonals_x = [a(1,:);c(1,:);b(1,:);d(1,:)];
            
            all_intersections = floor(diagonals_x([1:2:end])/plank_length) ~= floor(diagonals_x([2:2:end])/plank_length);
            intersections = all_intersections([1:2:end]) | all_intersections([2:2:end]);
            no_intersections = sum(intersections);
            
            piEstimate = 2 * app.SquaresEditField.Value/no_intersections;
            
            app.UIAxes.XTick = 0:plank_length:planks*plank_length;
            app.UIAxes.XLim = [0, planks*plank_length];
            app.UIAxes.YGrid = false;
            
            
            if squares > app.MaxPlotEditField.Value
                plot(app.UIAxes,x(:,1:app.MaxPlotEditField.Value),y(:,1:app.MaxPlotEditField.Value));
            else
                plot(app.UIAxes,x,y);
            end
            

        end
        
        function rootEstimate = estimate_root(app)
            squares = app.SquaresEditField.Value;
            side_length = app.SquareSizeEditField.Value;
            plank_length = app.PlankSizeEditField.Value;
            planks = app.PlanksEditField.Value;
            angles = rand(1,squares)*360;
            
            a = rand(2,squares)*planks*plank_length;
            b = a+[side_length/2*cosd(angles+90);side_length/2*sind(angles+90)];
            c = b+[side_length/2*cosd(angles+180);side_length/2*sind(angles+180)];
            d = c+[side_length/2*cosd(angles+270);side_length/2*sind(angles+270)];
            
            x = [a(1,:);b(1,:);c(1,:);d(1,:);a(1,:)];
            y = [a(2,:);b(2,:);c(2,:);d(2,:);a(2,:)];
            
            diagonals_x = [a(1,:);c(1,:);b(1,:);d(1,:)];
            
            all_intersections = floor(diagonals_x([1:2:end])/plank_length) ~= floor(diagonals_x([2:2:end])/plank_length);
            intersections = sum(all_intersections([1:2:end]) | all_intersections([2:2:end]));
            
            Ncc = sum(xor(all_intersections([1:2:end]),all_intersections([2:2:end])));
            rootEstimate = 2 - (Ncc/intersections);
            
            app.UIAxes.XTick = 0:plank_length:planks*plank_length;
            app.UIAxes.XLim = [0, planks*plank_length];
            app.UIAxes.YGrid = false;
            
            
            if squares > app.MaxPlotEditField.Value
                plot(app.UIAxes,x(:,1:app.MaxPlotEditField.Value),y(:,1:app.MaxPlotEditField.Value));
            else
                plot(app.UIAxes,x,y);
            end
        end
        
        function set_font(app)
            app.UIAxes.Title.Color = app.FontColourDropDown.Value;
            app.UIAxes.Title.FontSize = app.FontSizeEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.EstimateDropDown.Value == "Pi"
                estimate = estimate_pi(app);
                app.UIAxes.Title.String = ["Pi estimate:" num2str(estimate)];
            elseif app.EstimateDropDown.Value == "sqrt(2)"
                estimate = estimate_root(app);
                app.UIAxes.Title.String = ["sqrt(2) estimate:" num2str(estimate)];
            end
            set_font(app)
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            needles = app.NeedlesEditField.Value;
            needle_length = app.NeedleSizeEditField.Value;
            tiles = app.TileswhEditField.Value;
            tile_length = app.TileSizeEditField.Value;
            angles = rand(1,needles)*360;
            
            a = rand(2,needles)*tiles*tile_length;
            b = a+[needle_length*cosd(angles+90);needle_length*sind(angles+90)];
            
            x = [a(1,:);b(1,:)];
            y = [a(2,:);b(2,:)];
            
            x_intersections = floor(x(1,:)/tile_length) ~= floor(x(2,:)/tile_length);
            y_intersections = floor(y(1,:)/tile_length) ~= floor(y(2,:)/tile_length);
            intersections = sum(x_intersections | y_intersections);
            
            piEstimate = (needles*(4 * needle_length * tile_length - (needle_length*needle_length)))/(intersections*(tile_length*tile_length));
            app.UIAxes.Title.String = ["Pi estimate:" num2str(piEstimate)];
            
            app.UIAxes.XTick = 0:tile_length:tiles*tile_length;
            app.UIAxes.XLim = [0, tiles*tile_length];
            app.UIAxes.YTick = 0:tile_length:tiles*tile_length;
            app.UIAxes.YLim = [0, tiles*tile_length];
            app.UIAxes.YGrid = true;
            
            if needles > app.MaxPlotEditField_2.Value
                plot(app.UIAxes,x(:,1:app.MaxPlotEditField_2.Value),y(:,1:app.MaxPlotEditField_2.Value));
            else
                plot(app.UIAxes,x,y);
            end
            set_font(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlanksTab = uitab(app.TabGroup);
            app.PlanksTab.Title = 'Planks';
            app.RunButton = uibutton(app.PlanksTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EstimateDropDownLabel = uilabel(app.PlanksTab);
            app.EstimateDropDownLabel.Text = 'Estimate';
            app.EstimateDropDown = uidropdown(app.PlanksTab);
            app.EstimateDropDown.Items = {'Pi', 'sqrt(2)'};
            app.EstimateDropDown.Value = 'Pi';
            app.SquaresEditFieldLabel = uilabel(app.PlanksTab);
            app.SquaresEditFieldLabel.Text = 'Squares';
            app.SquaresEditField = uieditfield(app.PlanksTab, 'numeric');
            app.SquaresEditField.Limits = [1 1000000];
            app.SquaresEditField.ValueDisplayFormat = '%.0f';
            app.SquaresEditField.Value = 100000;
            app.SquareSizeEditFieldLabel = uilabel(app.PlanksTab);
            app.SquareSizeEditFieldLabel.Text = 'Square Size';
            app.SquareSizeEditField = uieditfield(app.PlanksTab, 'numeric');
            app.SquareSizeEditField.Limits = [0.1 1];
            app.SquareSizeEditField.Value = 0.1;
            app.PlanksEditFieldLabel = uilabel(app.PlanksTab);
            app.PlanksEditFieldLabel.Text = 'Planks';
            app.PlanksEditField = uieditfield(app.PlanksTab, 'numeric');
            app.PlanksEditField.Limits = [2 20];
            app.PlanksEditField.ValueDisplayFormat = '%.0f';
            app.PlanksEditField.Value = 5;
            app.PlankSizeEditFieldLabel = uilabel(app.PlanksTab);
            app.PlankSizeEditFieldLabel.Text = 'Plank Size';
            app.PlankSizeEditField = uieditfield(app.PlanksTab, 'numeric');
            app.PlankSizeEditField.Limits = [0.05 5];
            app.PlankSizeEditField.ValueDisplayFormat = '%.2f';
            app.PlankSizeEditField.Value = 0.2;
            app.MaxPlotEditFieldLabel = uilabel(app.PlanksTab);
            app.MaxPlotEditFieldLabel.Text = 'Max Plot';
            app.MaxPlotEditField = uieditfield(app.PlanksTab, 'numeric');
            app.MaxPlotEditField.Limits = [1 100];
            app.MaxPlotEditField.RoundFractionalValues = 'on';
            app.MaxPlotEditField.ValueDisplayFormat = '%.0f';
            app.MaxPlotEditField.Value = 100;
            app.TilesTab = uitab(app.TabGroup);
            app.TilesTab.Title = 'Tiles';
            app.NeedlesEditFieldLabel = uilabel(app.TilesTab);
            app.NeedlesEditFieldLabel.Text = 'Needles';
            app.NeedlesEditField = uieditfield(app.TilesTab, 'numeric');
            app.NeedlesEditField.Limits = [1 1000000];
            app.NeedlesEditField.ValueDisplayFormat = '%.0f';
            app.NeedlesEditField.Value = 10000;
            app.NeedleSizeEditFieldLabel = uilabel(app.TilesTab);
            app.NeedleSizeEditFieldLabel.Text = 'Needle Size';
            app.NeedleSizeEditField = uieditfield(app.TilesTab, 'numeric');
            app.NeedleSizeEditField.Limits = [0.05 2];
            app.NeedleSizeEditField.ValueDisplayFormat = '%.2f';
            app.NeedleSizeEditField.Value = 0.1;
            app.TileswhEditFieldLabel = uilabel(app.TilesTab);
            app.TileswhEditFieldLabel.Text = 'Tiles (w=h)';
            app.TileswhEditField = uieditfield(app.TilesTab, 'numeric');
            app.TileswhEditField.Limits = [2 10];
            app.TileswhEditField.RoundFractionalValues = 'on';
            app.TileswhEditField.ValueDisplayFormat = '%.0f';
            app.TileswhEditField.Value = 5;
            app.TileSizeEditFieldLabel = uilabel(app.TilesTab);
            app.TileSizeEditFieldLabel.Text = 'Tile Size';
            app.TileSizeEditField = uieditfield(app.TilesTab, 'numeric');
            app.TileSizeEditField.Limits = [0.1 5];
            app.TileSizeEditField.ValueDisplayFormat = '%.2f';
            app.TileSizeEditField.Value = 0.2;
            app.RunButton_2 = uibutton(app.TilesTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.MaxPlotEditField_2Label = uilabel(app.TilesTab);
            app.MaxPlotEditField_2Label.Text = 'Max Plot';
            app.MaxPlotEditField_2 = uieditfield(app.TilesTab, 'numeric');
            app.MaxPlotEditField_2.Limits = [1 100];
            app.MaxPlotEditField_2.RoundFractionalValues = 'on';
            app.MaxPlotEditField_2.ValueDisplayFormat = '%.0f';
            app.MaxPlotEditField_2.Value = 100;
            app.FontTab = uitab(app.TabGroup);
            app.FontTab.Title = 'Font';
            app.FontColourDropDownLabel = uilabel(app.FontTab);
            app.FontColourDropDownLabel.Text = 'Font Colour';
            app.FontColourDropDown = uidropdown(app.FontTab);
            app.FontColourDropDown.Items = {'Red', 'Green', 'Blue'};
            app.FontColourDropDown.Value = 'Red';
            app.FontSizeEditFieldLabel = uilabel(app.FontTab);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.FontTab, 'numeric');
            app.FontSizeEditField.Limits = [12 30];
            app.FontSizeEditField.RoundFractionalValues = 'on';
            app.FontSizeEditField.ValueDisplayFormat = '%.0f';
            app.FontSizeEditField.Value = 12;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Estimate')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [];
            app.UIAxes.Tag = 'axes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cw_1946553

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end